classdef DialogNew_Cruise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CruiseNameEditField       matlab.ui.control.EditField
        CruiseNameEditFieldLabel  matlab.ui.control.Label
        ACCEPTButton              matlab.ui.control.Button
        CLEARButton               matlab.ui.control.Button
        HelpTextArea              matlab.ui.control.TextArea
        HelpTextAreaLabel         matlab.ui.control.Label
        CreateNewCruiseLabel      matlab.ui.control.Label
        UITable2                  matlab.ui.control.Table
        DateDatePicker            matlab.ui.control.DatePicker
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp  %CallingApp corresponding with Perplex7.mlapp
        Ports % list of port names read from Ports.txt
        i   % event.Indices(1) row of UITable2
        j   % event.Indices(2) column UITable2
        Portname    % Departure and Arrival Port
        Latitude    % ... corresponding latitude
        Longitude   % ... corresponding longitude
        departDT % Depature and arrival date, time = 12:00 is fixed
        flg % flag to switch from departure to arrival port
        CR  % Cruise name
        SC  % table containing port name, position and datetime
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
% %             app.UITable2.Port(1).Value = "Departure";
            app.CallingApp = mainapp;
            sz = [2 3];
            varTypes = {'string','string','string'};
            varNames = {'Port','Name','Date_Time'};
            A = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            A.Port(1) = "Departure";
            A.Name(1) = "";
            A.Date_Time(1) = "";
            A.Port(2) = "Arrival";
            A.Name(2) = "";
            A.Date_Time(2) = "";
            app.UITable2.Data = A;
            portfile = fullfile(app.CallingApp.pn,'Dataset','Ports.txt');
            app.Ports = import_port(portfile);
            app.UITable.Data = app.Ports.Port;
            
            app.Portname = app.Ports.Port(1);
            app.Latitude = app.Ports.Latitude(1);
            app.Longitude = app.Ports.Longitude(1);
            
            app.flg = 1;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.i = event.Indices(1);
            app.j = event.Indices(2);
            app.Portname = app.Ports.Port(app.i,app.j);
            app.Latitude = app.Ports.Latitude(app.i,app.j);
            app.Longitude = app.Ports.Longitude(app.i,app.j);
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            if app.flg
                i = 1;
                app.flg = 0;
            else
                i = 2;
            end
            newDT = app.DateDatePicker.Value;
            dt = datevec(newDT);
            dnum_newDT = datenum([dt(1:3) 12 0 0]);
            app.departDT = datestr(dnum_newDT);
            app.UITable2.Data.Name(i) = app.Portname;
            app.UITable2.Data.Date_Time(i) = string(app.departDT);           
            app.UITable2.Data.Latitude(i) = app.Latitude;           
            app.UITable2.Data.Longitude(i) = app.Longitude;           
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            sz = [2 3];
            varTypes = {'string','string','string'};
            varNames = {'Port','Name','Date_Time'};
            A = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            A.Port(1) = "Departure";
            A.Name(1) = "";
            A.Date_Time(1) = "";
            A.Port(2) = "Arrival";
            A.Name(2) = "";
            A.Date_Time(2) = "";
            app.UITable2.Data = A;   
            app.flg = 1;
        end

        % Button pushed function: ACCEPTButton
        function ACCEPTButtonPushed(app, event)
            app.SC = app.UITable2.Data;
            app.CR = app.CruiseNameEditField.Value;
            % Create Station table and Cast table for the new cruise
            New_Cruise_table(app.SC,app.CR,app.CallingApp.pn);
            update_cruise(app.CallingApp);
            % Delete the dialog box
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Port'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Port'; 'Name'; 'Date_Time'; 'Latitude'; 'Longitude'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true true true];
            app.CreateNewCruiseLabel = uilabel(app.UIFigure);
            app.CreateNewCruiseLabel.Text = 'Create New Cruise';
            app.HelpTextAreaLabel = uilabel(app.UIFigure);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.UIFigure);
            app.HelpTextArea.Value = {'1. Enter Cruise name, e.g. PS117'; '2. Select port of departure from the port list.'; '3. Enter date of departure'; '4. Select port of arrival from the port list.'; '5. Enter date of arrival'; ''; 'If the port is not shown in the list, select any port and change the table below.'; ''; 'Use CLEAR for correction'; 'or ACCEPT to continue'; ''; 'Change time (12:00) later if necessary '; ''};
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.ACCEPTButton = uibutton(app.UIFigure, 'push');
            app.ACCEPTButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTButtonPushed, true);
            app.ACCEPTButton.Text = 'ACCEPT';
            app.CruiseNameEditFieldLabel = uilabel(app.UIFigure);
            app.CruiseNameEditFieldLabel.Text = 'Cruise Name';
            app.CruiseNameEditField = uieditfield(app.UIFigure, 'text');
            app.CruiseNameEditField.Value = 'PS117';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogNew_Cruise(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end