classdef Perplex7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        InOutMenu                       matlab.ui.container.Menu
        ImportfromtxtfileMenu           matlab.ui.container.Menu
        ExporttoExcelMenu               matlab.ui.container.Menu
        DailyscheduleMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        CruiseTab                       matlab.ui.container.Tab
        clearButton                     matlab.ui.control.Button
        openButton                      matlab.ui.control.Button
        SeaiceconcentrationHDFfileEditField  matlab.ui.control.EditField
        SeaiceconcentrationHDFfileEditFieldLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ContactGerdRohardtewetelnetLabel  matlab.ui.control.Label
        AuthorGerdRohardtLabel          matlab.ui.control.Label
        Release1500fromAugust2024Label  matlab.ui.control.Label
        ProgramforExpeditionRoutePLanningandEXecutionLabel  matlab.ui.control.Label
        Perplex7Label                   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        NumberoftheFirstStationEditField  matlab.ui.control.NumericEditField
        NumberoftheFirstStationEditFieldLabel  matlab.ui.control.Label
        ChangeArrivalTimeHHMMEditField  matlab.ui.control.EditField
        HoursMinutesLabel               matlab.ui.control.Label
        RemainingTimeDaysHoursEditField  matlab.ui.control.EditField
        RemainingTimeDaysHoursEditFieldLabel  matlab.ui.control.Label
        EntertheCommenttosearchintheStationTableLabel  matlab.ui.control.Label
        ChangeArrivalDateDatePicker     matlab.ui.control.DatePicker
        ChangeArrivalDateDatePickerLabel  matlab.ui.control.Label
        ArrivalDateTimeEditField        matlab.ui.control.EditField
        ArrivalDateTimeEditFieldLabel   matlab.ui.control.Label
        RemainingTimetoEditField        matlab.ui.control.EditField
        RemainingTimetoEditFieldLabel   matlab.ui.control.Label
        TimetogodayshoursEditField      matlab.ui.control.EditField
        TimetogodayshoursEditFieldLabel  matlab.ui.control.Label
        RemainingTimetoPortdayshoursEditField  matlab.ui.control.EditField
        RemainingTimetoPortdayshoursEditFieldLabel  matlab.ui.control.Label
        CurrentTimeuseddayshoursEditField  matlab.ui.control.EditField
        CurrentTimeuseddayshoursEditFieldLabel  matlab.ui.control.Label
        AvailableTimedayshoursEditField  matlab.ui.control.EditField
        AvailableTimedayshoursEditFieldLabel  matlab.ui.control.Label
        AvgSpeedknotsEditField          matlab.ui.control.NumericEditField
        AvgSpeedknotsEditFieldLabel     matlab.ui.control.Label
        TotalRoutenauticalmilesEditField  matlab.ui.control.NumericEditField
        TotalRoutenauticalmilesEditFieldLabel  matlab.ui.control.Label
        ArrivalEditField                matlab.ui.control.EditField
        ArrivalEditFieldLabel           matlab.ui.control.Label
        DepartureEditField              matlab.ui.control.EditField
        DepartureEditFieldLabel         matlab.ui.control.Label
        CruiseEditField                 matlab.ui.control.EditField
        CruiseEditFieldLabel            matlab.ui.control.Label
        StationsTab                     matlab.ui.container.Tab
        CloneCastSetupfromPanel_2       matlab.ui.container.Panel
        ClearButton_3                   matlab.ui.control.Button
        CloneButton_2                   matlab.ui.control.Button
        toNrEditField_2                 matlab.ui.control.NumericEditField
        toNrEditField_2Label            matlab.ui.control.Label
        fromNrEditField                 matlab.ui.control.NumericEditField
        fromNrEditFieldLabel            matlab.ui.control.Label
        fromStationTabLabel_2           matlab.ui.control.Label
        CloneNrEditField                matlab.ui.control.NumericEditField
        CloneNrEditFieldLabel           matlab.ui.control.Label
        ChangeSpeedPanel                matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        ChangeButton                    matlab.ui.control.Button
        ShipSpeedknotsEditField         matlab.ui.control.NumericEditField
        ShipSpeedknotsEditFieldLabel    matlab.ui.control.Label
        ConvertDMtoDegreesPanel         matlab.ui.container.Panel
        DDMMmmEditField                 matlab.ui.control.EditField
        DDMMmmLabel                     matlab.ui.control.Label
        DDddEditField                   matlab.ui.control.NumericEditField
        DDddEditFieldLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Lamp                            matlab.ui.control.Lamp
        UpdateButton                    matlab.ui.control.Button
        ChangeListButtonGroup           matlab.ui.container.ButtonGroup
        orderButton                     matlab.ui.control.RadioButton
        ClearButton                     matlab.ui.control.Button
        EndRowEditField                 matlab.ui.control.NumericEditField
        EndRowEditFieldLabel            matlab.ui.control.Label
        InsertafterRowEditField         matlab.ui.control.NumericEditField
        InsertafterRowEditFieldLabel    matlab.ui.control.Label
        StartRowEditField               matlab.ui.control.NumericEditField
        StartRowEditFieldLabel          matlab.ui.control.Label
        ExecuteButton                   matlab.ui.control.Button
        Lamp_5                          matlab.ui.control.Lamp
        XpasteButton                    matlab.ui.control.RadioButton
        CpasteButton                    matlab.ui.control.RadioButton
        insertButton                    matlab.ui.control.RadioButton
        deleteButton                    matlab.ui.control.RadioButton
        CastsTab                        matlab.ui.container.Tab
        DurationHHMMEditField           matlab.ui.control.EditField
        DurationHHMMEditFieldLabel      matlab.ui.control.Label
        WaytogonmEditField              matlab.ui.control.NumericEditField
        WaytogonmEditFieldLabel         matlab.ui.control.Label
        WaterDepthmEditField            matlab.ui.control.NumericEditField
        WaterDepthmEditFieldLabel       matlab.ui.control.Label
        StationNumberEditField          matlab.ui.control.NumericEditField
        StationNumberEditFieldLabel     matlab.ui.control.Label
        PositionEditField               matlab.ui.control.EditField
        PositionEditFieldLabel          matlab.ui.control.Label
        ChangeCastsPanel                matlab.ui.container.Panel
        DeleteselectedCastButton        matlab.ui.control.Button
        Lamp_4                          matlab.ui.control.Lamp
        UpdateDateTimeButton            matlab.ui.control.Button
        shiftcastdownButton             matlab.ui.control.Button
        shiftcastupButton               matlab.ui.control.Button
        DirectUpdateDateTimeCheckBox    matlab.ui.control.CheckBox
        InsertButton                    matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        SetEndofCastPanel               matlab.ui.container.Panel
        Lamp_2                          matlab.ui.control.Lamp
        UpdateEndofCastButton           matlab.ui.control.Button
        SetTimeHMEditField              matlab.ui.control.EditField
        SetTimeHMEditFieldLabel         matlab.ui.control.Label
        SetDateDatePicker               matlab.ui.control.DatePicker
        SetDateDatePickerLabel          matlab.ui.control.Label
        MapTab                          matlab.ui.container.Tab
        GEBCOCheckBox                   matlab.ui.control.CheckBox
        DepthEditField                  matlab.ui.control.EditField
        DepthEditFieldLabel             matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        EEZCheckBox                     matlab.ui.control.CheckBox
        TextArea                        matlab.ui.control.TextArea
        NrinStationTabEditField         matlab.ui.control.NumericEditField
        NrinStationTabEditFieldLabel    matlab.ui.control.Label
        ClearButton_2                   matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        InsertButton_2                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        InstrumentTab                   matlab.ui.container.Tab
        UnitsTextArea                   matlab.ui.control.TextArea
        UnitsTextAreaLabel              matlab.ui.control.Label
        SortButton                      matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        Lamp_3                          matlab.ui.control.Lamp
        UpdateButton_3                  matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        js  % selected row of table Stations
        js1 % selected column of table Stations
        jc  % selected jc(1) selected row, jc(2) selected column of table Cast
        ji  % the selected row in the instrument list in Cast tab
        sc  % number of colums in UITable and UITable2
        newDT   % new DateTime -> when setting the end of an cast
        flg % flag needed to set end date/time of a cast
        INSTR   % used Instruments
        insertInstr % selected Instrument to be inserted in Cast table
        data % to be used for map plotting routines; see app.data.LATLIM etc.
        hh2 % plotm handle 
        mstruct % axesm handle
        h   % h(1) ... h(6) handle for lines and markers
        hh1 % plotm handle
    end
    
    properties (Access = public)
        pn  % pathname taken during startup
        CR  % new cruise name.
        rowm % selected row in Station tab
        selwp % nr of waypoint/station to compute remaining time up to here
        err % error messages from check_datasets
        PS  % Structure
        fn  % filename
        S   % Stations or Wayponits (table)
        C   % Casts (cell)
        H   % Header (structure)
        I   % Instruments (table)
        dtrow   % row of selected DateTim in Station Table
        DTtmp   % save DateTime in selected row before it was changed 
        plteez % controls plotting of eez
        pltgebco % controls plotting of gebco contourlines
        scipn % pathname of the HDF-file
        scifn % filename of the HDF-file
        SCIfile % string with path and filename
        depthcontour % draw contour lines in map tab
        land % uses land shape file to plot worldmap
        G % G.latgrd  latitude from GRIDONE_2D.mat
          % G.longrd  % longitude from GRIDONE_2D.mat
          % G.Zgrd    % Z == waterdepth from GRIDONE_2D.mat
        GS % like G but for the subregion bounded by data.latlim and data.lonlim
        EEZnotInDataset % == 1 if EEZ shape files are not in folder "Dataset"
                        % == 0 if EEZ shape files are stored in folder
                        % "Dataset"
    end 
    
    
    properties (Access = private)
        DialogApp   % see DialogNew_Cruise.mlapp
        DialogApp1  % see DialogSaveAs.mlapp
        newcr   % see DialogSaveAs
    end
    
    methods (Access = public)
        
        function update_cruise(app)
            startupFcn(app);
        end
                
        function update_saveas(app,newcr)
            % see DialogSaveAs.mlapp
            app.CruiseEditField.Value = newcr; 
            % save PSnew in subfolder Cruise
            outfile = fullfile(app.pn,'Cruise',[newcr '.mat']);
            S = app.S;
            C = app.C;
            H = app.H;
            I = app.I;
            save(outfile,'S','C','H','I');
            % update P.CRUISE
            P.CRUISE = [newcr '.mat'];
            % ... and save in file PSnew_cfg.mat in subfolder Cruise
            outfile = fullfile(app.pn,'Cruise',[newcr '_cfg.mat']);
            save(outfile,'P');
            % update Perplex7_cfg.mat which is located in main program
            % folder
            outfile = fullfile(app.pn,'Perplex7_cfg.mat');
            save(outfile,'P');          
        end
        
    end
    
    methods (Access = private)
                
        function update_tab_cruise(app)
            % Update informations in [Tab Cruise]
            app.DepartureEditField.Value = [app.H.DeparturePort '; ' app.H.DepartureDateTime];            
            app.ArrivalEditField.Value = [app.H.ArrivalPort '; ' app.H.ArrivalDateTime];
            % Station number counting:
            app.NumberoftheFirstStationEditField.Value = app.H.StartStationNumber;
            % compute cruise details
            app.H = cruise_info(app.H,app.S);
            % display output:
            app.AvailableTimedayshoursEditField.Value = app.H.TotalTime;
            app.CurrentTimeuseddayshoursEditField.Value = app.H.WorkAtSea;
            app.TotalRoutenauticalmilesEditField.Value = app.H.Route;
            app.AvgSpeedknotsEditField.Value = app.H.AvgShipSpeed;
            app.TimetogodayshoursEditField.Value = app.H.TimeTOgo;
            app.RemainingTimetoPortdayshoursEditField.Value = app.H.RemainTime;
            app.RemainingTimetoEditField.Value = app.H.SelectedWP;
            app.ArrivalDateTimeEditField.Value = app.H.ArrivalDateTimeSelectedWP;
            app.RemainingTimeDaysHoursEditField.Value = app.H.RemainTimeToWP;
        end

        function app = check_datasets(app)
            % checking the required datasets
            % (1) gridone.grd; format: netCDF; 1-minute-grid
            % download from: https://www.gebco.net/data_and_products/historical_data_sets/#gebco_one
            %
            % app.pn is the root path from Perplex7.mlapp
            %
            % enter subfolders from app.pn (see startupFcn) and dataset
            % name to be checked:
            % 1. Gebco one minute topography
            subfolder(1) = "Dataset";
            datasetname(1) = "GRIDONE_2D.mat";
            % 2. List of ports with latitude and longitude
            subfolder(2) = "Dataset";
            datasetname(2) = "Ports.txt";
            % 3. Instrument settings to calculate time for deployment
            subfolder(3) = ""; % in program root folder
            datasetname(3) = "Default_InstrTable.csv";
            % 4. landareas
            subfolder(4) = "Dataset";
            datasetname(4) = "landareas.shp";
            % 5. EEZ boundaries 
            subfolder(5) = "Dataset";
            datasetname(5) = "eez_v11.shp";

            search_name = fullfile(app.pn,subfolder,datasetname);

            j = 0;
            app.err = '';
            % here do not check eez_v11.shp
            for i = 1:length(search_name)-1
                s = dir(search_name(i));
                if isempty(s)
                    j = j+1;
                    err(j) = search_name(i);
                end
            end
            if j > 0
                app.err = err;
            end
            %
            s = dir(search_name(5));
            if isempty(s)
                app.EEZCheckBox.Enable = "off";
                app.EEZCheckBox.Value = 0;
                app.plteez = 0;
                app.EEZnotInDataset = 1;
            else
                app.EEZCheckBox.Enable = "on";
                app.EEZCheckBox.Value = 0;
                app.plteez = 0;
                app.EEZnotInDataset = 0;
            end           
        end

        function app = update_map(app)
            % update track, stations and waypoints only
            % (1) ship track
            hold(app.UIAxes,'on');
            if ~isnan(app.h(1))
                delete(app.h(1));
                % plot the ships track, for S.Used == 1 only (black)
                [lat,lon] = maptriml(app.S.Latitude(app.S.Used==1),app.S.Longitude(app.S.Used==1),app.data.LATLIM,app.data.LONLIM);
                app.h(1) = plotm(lat,lon,'k-');
                set(app.h(1),'LineWidth',1);
            end
            % (2) plot marker at way points (black)
            if ~isnan(app.h(2))
                delete(app.h(2));
                app.h(2)=plotm(app.S.Latitude(strcmp(app.S.Type,'WP') & ...
                    app.S.Used==1), ...
                    app.S.Longitude(strcmp(app.S.Type,'WP') & ...
                    app.S.Used==1));
                set(app.h(2),'LineStyle','none','Marker','.',...
                    'MarkerSize',12,...
                    'MarkerEdgeColor',[0 0 0], ...
                    'MarkerFaceColor',[0 0 0]);
            end
            % (3) plot marker at stations (red)
            if ~isnan(app.h(3))
                delete(app.h(3));
                latST = app.S.Latitude(strcmp(app.S.Type,'ST') & ...
                    app.S.Used==1);
                lonST = app.S.Longitude(strcmp(app.S.Type,'ST') & ...
                    app.S.Used==1);
                if ~isempty(latST)
                    app.h(3) = plotm(latST, lonST);
                    set(app.h(3),'LineStyle','none','Marker','.',...
                        'MarkerSize',12,...
                        'MarkerEdgeColor',[1 0 0], ...
                        'MarkerFaceColor',[1 0 0]);
                end
            end
            % (4) plot marker at way points and stations if S.Done == 1 (green)
            if ~isnan(app.h(4))
                delete(app.h(4));
            end
                latDone = app.S.Latitude(app.S.Done==1);
                lonDone = app.S.Longitude(app.S.Done==1);
                if ~isempty(latDone)
                    app.h(4)=plotm(latDone, lonDone);
                    set(app.h(4),'LineStyle','none','Marker','.',...
                        'MarkerSize',12,...
                        'MarkerEdgeColor',[0 1 0], ...
                        'MarkerFaceColor',[0 1 0]);
                else
                    app.h(4) = NaN;
                end
            
            % (5) plot marker at way points and stations if not used: ...
            % S.Used == 0 (black star)
            if ~isnan(app.h(5))
                delete(app.h(5));
            end
            latUsed = app.S.Latitude(app.S.Used==0);
            lonUsed = app.S.Longitude(app.S.Used==0);
            if ~isempty(latUsed)
                app.h(5)=plotm(latUsed,lonUsed);
                set(app.h(5),'LineStyle','none','Marker','*',...
                    'MarkerSize',6,...
                    'MarkerEdgeColor',[0 0 0], ...
                    'MarkerFaceColor',[0 0 0]);
            else
                app.h(5) = NaN;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Check needed data to run Perplex7.mlapp and look for
        % Perplex7_cfg.mat. In this file the last session was saved. If
        % Perplex7_cfg.mat did not exist main folder, DialogNew_Cruise(app)
        % was executed.
            % setup startup settings
            app.dtrow = 0;

            % check path from Perplex.mlappapp.latcenter
            app.pn = cd;
            app = check_datasets(app);
            if ~ isempty(app.err)
                fig_err = app.UIFigure;
                fmt = '';
                for i = 1:length(app.err)
                    fmt = [fmt '%s\n'];
                end
                s = sprintf(fmt,'Dataset:',app.err);
                uialert(fig_err,s,'Required Datasets are missing')
            end
            % Load GEBCO 1 minute topography: See Perplex7-Manual,
            % Chapter Installation, item 6:
            grdfile = fullfile(app.pn,'Dataset','GRIDONE_2D.mat');
            load(grdfile);
            app.G.latgrd = lat;
            app.G.longrd = lon;
            app.G.Zgrd = Z;
            % latgrd, longrd, zgrd used in update_DateTime
            clear lat lon Z;

            % load landareas
            landfile = fullfile(app.pn,'Dataset','landareas.shp');
            app.land = readgeotable(landfile);

            % start creating a new cruise if Perplex7_cfg.mat did not exist
            infile = fullfile(app.pn,'Perplex7_cfg.mat');
            if ~isempty(dir(infile))
                % Perplex7_cfg.mat exists
                load(infile);
                % P = 
                % CRUISE: e.g. 'PS117.mat'
                % display the cruisename in the tab "Cruise"
                [~,app.fn,~] = fileparts(P.CRUISE);
                % Fill Info in [Tab Cruise]
                app.CruiseEditField.Value = app.fn;

                % e.g. PS117.mat is located in subfolder ..\Cruise
                infile = fullfile(app.pn,'Cruise',P.CRUISE);          
                % Add data to the Table UI Component
                Tdata = load(infile);            
                app.C = Tdata.C; % Casts
                app.S = Tdata.S; % Stations or Waypoints
                app.H = Tdata.H; % Header
                app.I = Tdata.I; % Instruments
                clear Tdata;
                if ~isfield(app.H,'StartStationNumber')
                    app.H.StartStationNumber = 1;
                end
                app.UITable.Data = app.S;
                app.UITable2.Data = app.C{1,1};
                % 
                update_tab_cruise(app);
                % startup Instrument List
                [app.INSTR] = Instruments_Selected(app.I);
                app.UITable3.Data = app.INSTR;
                app.UITable4.Data = app.I;            
                % LAT/LON Limits for Map display
                    sz = [2 3];
                    varTypes = {'string','double','double'};
                    varNames = {'Name','Min','Max'};
                    A = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    A.Name(1) = "LAT";
                    A.Min(1) = NaN;
                    A.Max(1) = NaN;
                    A.Name(2) = "LON";
                    A.Min(2) = NaN;
                    A.Max(2) = NaN;
                    app.UITable5.Data = A;
                app.data.LATLIM = [min(app.S.Latitude) max(app.S.Latitude)];
                app.data.LONLIM = [min(app.S.Longitude) max(app.S.Longitude)];
                app.UITable5.Data.Min(1) = app.data.LATLIM(1);
                app.UITable5.Data.Max(1) = app.data.LATLIM(2);
                app.UITable5.Data.Min(2) = app.data.LONLIM(1);
                app.UITable5.Data.Max(2) = app.data.LONLIM(2);

                % subset of GEBCO Grid
                app.GEBCOCheckBox.Value = 0;
                app.GS = GRIDONE_2D_subset(app.G,app.data);
                

                % if port of departure and port of arrival is the same plot
                % worldmap
                latrange = range(app.data.LATLIM);
                lonrange = range(app.data.LONLIM);
                if latrange == 0 && lonrange == 0
                    app.UITable5.Data.Min(1) = -90;
                    app.UITable5.Data.Max(1) = 90;
                    app.UITable5.Data.Min(2) = -180;
                    app.UITable5.Data.Max(2) = 180;
                    app.GS = app.G;
                    app.GEBCOCheckBox.Value = 0;
                    app.data.LATLIM = [-90 90];
                    app.data.LONLIM = [-180 180];
                end
                % Plot Map
                cla(app.UIAxes);
                app.scifn =  app.SeaiceconcentrationHDFfileEditField.Value;
                app.scipn = fullfile(app.pn,'Dataset');
                if isempty(app.scifn)
                    app.SCIfile ='';
                else
                    app.SCIfile = fullfile(app.scipn,app.scifn);
                    app.EEZCheckBox.Value = 0;
                    app.GEBCOCheckBox.Value = 0;
                end
                % read depth contour lines:
                app.pltgebco = app.GEBCOCheckBox.Value;
                app.plteez = app.EEZCheckBox.Value;
                app.depthcontour = str2num(app.DepthEditField.Value);

                [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);

            else 
                % no Perplex7_cfg.mat found --> create an new cruise:
                app.DialogApp = DialogNew_Cruise(app);
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
        % Users can change table Station columns: Proc, Active, DateTime,
        % Latitude, Longitude, Depth and Speed) Also they can reset Delay
        % to 0.
              if app.dtrow > 1 
                  % Here DateTime was selected (see
                  % UITableCellSelection) In this special case the changed
                  % DateTime results in a Delay (in hours) in the previous
                  % row.
                  % Read modified DateTime and compute difference with
                  % DateTime before it was changed; see app.DTtmp.
                  x = app.UITable.Data.DateTime(app.dtrow);
                  dtdiff = x - app.S.DateTime(app.dtrow);
                  app.UITable.Data.Delay(app.dtrow-1) = hours(dtdiff);
                  app.dtrow = 0; % reset
              end
              % The red light indicates to press [Update] to recompute
              % table Station and Cast and update Map Plot too.
              % See: UpdateButtonPushed(app, event)
              app.Lamp.Color = [1 0 0];
              app.S = app.UITable.Data;
        end

        % Cell selection callback: UITable, UITable2
        function UITableCellSelection(app, event)
        % Here we must make a selection which UITable was selected,
        % therfore we find out the number of columns: 
        app.sc = length(event.Source.ColumnEditable);
        % app.sc = 15; in UITable in Station tab has 15 columns
        % app.sc = 10; in UITable2 in Cast tab has 10 columns
        
        app.flg = 0; % need to set end of a cast; see below
            switch app.sc
                case 15
                    % reset Nr in table Station
                    try 
                       delete(app.hh1);
                    catch
                    end
                    % prüfen
                    app.S.Nr = abs(app.S.Nr);
                    app.UITable.Data = app.S;
                    
                    app.js = event.Indices(1); % row of table Stations
                    app.js1 = event.Indices(2); % column of table Stations
                    
                    if app.js1 == 1 % select in table Stations row == js and column == 1 (--> S.Nr)
                        try % delete previous selection plotted in the map
                            delete(app.hh2);
                            pause(0.5);
                        catch
                        end

                        % Update Map to indicate the selected waypoint or
                        % station:
                        app.rowm = app.js;
                        app.NrinStationTabEditField.Value = app.rowm;
                        app.hh2=plotm(app.S.Latitude(app.rowm),app.S.Longitude(app.rowm));
                        set(app.hh2,'LineStyle','none','Marker','.',...
                            'MarkerSize',20,...
                            'MarkerEdgeColor',[0 0 1], ...
                            'MarkerFaceColor',[0 0 1]);

                        % Get the selected row number (S.Nr) and write row
                        % number in in panel "Change List" if they will be
                        % needed here.
                        if app.StartRowEditField.Value == 0
                            app.StartRowEditField.Value = app.js;
                            app.ExecuteButton.Enable = 1;
                            app.ChangeButton.Enable = 1;
                        elseif app.EndRowEditField.Value == 0
                            app.EndRowEditField.Value = app.js;
                        elseif app.InsertafterRowEditField.Value == 0
                            app.InsertafterRowEditField.Value = app.js;
                        end
                    elseif app.js1 == 5 % select row and column 5 which is the StatNr.
                        % for the selected row update UITable2 in Cast Tab:
                        % and complete the station details in Cast tab
                        % whether it is a waypoint or a station
                        app.UITable2.Data = app.C{1,app.js};
                        if ~isnan(app.S.StatNr(app.js)) % station
                            app.StationNumberEditField.Value = app.S.StatNr(app.js);
                            app.PositionEditField.Value = latlong2position(app.S.Latitude(app.js),app.S.Longitude(app.js));
                            app.WaterDepthmEditField.Value = app.S.Depth(app.js);
                            app.WaytogonmEditField.Value = round(app.S.Dist(app.js));
                            [~,~,~,HH,MM,~] = datevec(app.S.Duration(app.js)./24);
                            app.DurationHHMMEditField.Value= [num2str(HH) ':' num2str(MM)];
                        else % waypoint
                            app.StationNumberEditField.Value = Inf;
                            app.PositionEditField.Value = latlong2position(app.S.Latitude(app.js),app.S.Longitude(app.js));
                            app.WaterDepthmEditField.Value = app.S.Depth(app.js);
                            app.WaytogonmEditField.Value = round(app.S.Dist(app.js));
                            app.DurationHHMMEditField.Value= '';
                        end
                    elseif app.js1 == 6 % select row and column 6 (to change DateTime)
                        app.dtrow = app.js;
                        % save app.S.DateTime(app.js);
                        app.DTtmp = app.S.DateTime(app.dtrow);
                    end                    
                case 10 % UITable2 was selected
                    app.jc = event.Indices;
                    if app.jc(2) == 4 % to set end date/time of cast
                        app.newDT = app.C{1, app.js}.DateTime(app.jc(1));
                        app.SetDateDatePicker.Value = app.newDT;
                        app.flg = 1;
                    end
            end
        end

        % Display data changed function: UITable2
        function UITable2DisplayDataChanged(app, event)
        % If UITable2 (Cast) was changed, e.g. this can be the "Depth"
        % update_DateTime was executed depending on Direct Update Date Time
        % was on or off. A red light indicates that the user must press
        % Update button; see UpdateDateTimeButtonPushed
                app.Lamp_4.Color = [1 0 0];
                pause(0.5);
                if app.DirectUpdateDateTimeCheckBox.Value
                    % if direct update is indicated, than update_DateTime
                    % was executed immidiatly after each change.
                    app.C{1,app.js} = app.UITable2.Data;
                    % update_DateTime
                    [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                    update_tab_cruise(app);
                    app.UITable2.Data = app.C{1,app.js};
                    app.UITable.Data = app.S;
                    app.Lamp_4.Color = [0 1 0];
                    pause(0.5);
                else
                    % ... or light stays red meaning that [Update DateTime]
                    % can be pressed later
                    app.C{1,app.js} = app.UITable2.Data;
                    app.Lamp_4.Color = [1 0 0];
                end
        end

        % Button pushed function: UpdateEndofCastButton
        function UpdateEndofCastButtonPushed(app, event)
        % app.flg = 1 if a Cast was selected in table Cast 
        % (see: UITableCellSelection(app, event). In this case the user can
        % use DatePicker to enter the end of the cast
       
            if app.flg % to set end of cast            
                app.newDT = app.SetDateDatePicker.Value;
                value = app.SetTimeHMEditField.Value;
                % real end of cast 
                hms = str2num(value);
                dt = datevec(app.newDT);
                dnum_eoc_new = datenum([dt(1:3) hms 0]);
                % planned end of cast 
                duration = app.C{1, app.js}.Duration(app.jc(1))/24;
                dnum_eoc = datenum(app.C{1, app.js}.DateTime(app.jc(1))) + duration;
                % delay in hours
                app.C{1, app.js}.Delay(app.jc(1)) = (dnum_eoc_new - dnum_eoc).* 24;
                app.UITable2.Data = app.C{1,app.js};
            end
            app.C{1,app.js} = app.UITable2.DisplayData;            
            app.S = app.UITable.DisplayData;           
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            [~,~,~,HH,MM,~] = datevec(app.S.Duration(app.js)./24);
            app.DurationHHMMEditField.Value= [num2str(HH) ':' num2str(MM)];
            update_tab_cruise(app);
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,app.js};
            app.Lamp_2.Color = [0 1 0];
            app.flg = 0;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
        % Update Cruise after any change in table Station
            app.S = app.UITable.DisplayData;
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            update_tab_cruise(app);
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,1};
            % update track, stations and waypoints only
            app = update_map(app);
            % Update Cruise Details:
            app.H = cruise_info(app.H,app.S);
            % display output:
            app.AvailableTimedayshoursEditField.Value = app.H.TotalTime;
            app.CurrentTimeuseddayshoursEditField.Value = app.H.WorkAtSea;
            app.TotalRoutenauticalmilesEditField.Value = app.H.Route;
            app.AvgSpeedknotsEditField.Value = app.H.AvgShipSpeed;
            app.TimetogodayshoursEditField.Value = app.H.TimeTOgo;
            app.RemainingTimetoPortdayshoursEditField.Value = app.H.RemainTime;
            % green light indicates that everthing is up to date
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
        % In Tab Stations there is a panel "Change List", which allows
        % users to change the table Stations incl. the belonging Cast
        % 
           app.Lamp_5.Color = [1 0 0];
           pause(0.5);
           app.S = app.UITable.DisplayData;
           if app.insertButton.Value 
               % e.g. Start Row = 10 will duplicate row 10; End Row = 0 and
               % Insert after Row = 0
               action = 'insert';
           elseif app.deleteButton.Value
               % e.g. Start Row = 10, End Row = 0, Insert after Row = 0;
               % will delete Row 10 only
               % or Start Row = 10, End Row = 15, Insert after Row = 0;
               % will delete 6 rows
               action = 'delete';
           elseif app.CpasteButton.Value
               % or Start Row = 10, End Row = 11, Insert after Row = 14;
               % will copy and insert these 2 rows after Row 14.              
               action = 'cpaste';
           elseif app.XpasteButton.Value
               % or Start Row = 10, End Row = 11, Insert after Row = 14;
               % will move these 2 rows and place after Row 14.              
               action = 'xpaste';
           elseif app.orderButton.Value
               % or Start Row = fist + 1, End Row = last - 1, Insert after
               % Row = 0 will change the order; e.g. you may have the
               % waypoints from Bremerhaven to Cape Town, but the departure
               % should be Cape Town and arrival Bremerhaven. In this case,
               % users can simply insert the waypoints as usal and
               % afterwards change the order.
               action = 'order';
           end
          [action,row] = Check_selected_rows(action,app.StartRowEditField.Value, ...
                                                    app.EndRowEditField.Value, ...
                                                    app.InsertafterRowEditField.Value);     
          [app.H,app.S,app.C] = Change_Stat_Cast(app.H,app.S,app.C,action,row,0);
          % update_DateTime
          [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
          % display table Station and Cast
          app.UITable.Data = app.S;
          app.UITable2.Data = app.C{1,app.js};
          % reset panel Change List
          app.StartRowEditField.Value = 0;
          app.EndRowEditField.Value = 0;
          app.InsertafterRowEditField.Value = 0;
          app.ExecuteButton.Enable = 0;
          app.ChangeButton.Enable = 0;
          % update cruise info
          update_tab_cruise(app);
          % update track, stations and waypoints only
          app = update_map(app);
          
          app.Lamp_5.Color = [0 1 0];
          pause(0.5);         
        end

        % Display data changed function: UITable4
        function UITable4DisplayDataChanged(app, event)
        % Table in Tab Instrument; user can modify the table
            app.I = app.UITable4.DisplayData;
            app.Lamp_3.Color = [1 0 0];
        end

        % Button pushed function: UpdateButton_3
        function UpdateButton_3Pushed(app, event)
        % Process the current state of the Instrument table 
            % reset Instruments shown in Tab Cast
            app.UITable3.Data = {};
            % get Instrument names of active instruments only
            [app.INSTR] = Instruments_Selected(app.I);
            % put Instruments in UITable3
            app.UITable3.Data = app.INSTR;
            app.Lamp_3.Color = [0 1 0];
            % update DateTime after Instrument Settings were changed
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,1};
        end

        % Button pushed function: SortButton
        function SortButtonPushed(app, event)
        % Sort Instrument table, (a) shift Active = 1 on top, (b) shift
        % Active = 0 below, (c) sort both "Instrument" ascending
            TABused = app.I(app.I.Used == 1,:);
            TABused = sortrows(TABused,'Instrument','ascend');
            TABnotused = app.I(app.I.Used == 0,:);
            TABnotused = sortrows(TABnotused,'Instrument','ascend');
            app.I = vertcat(TABused,TABnotused);
            app.UITable4.Data = app.I;
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
        % Insert or add an instrument to a waypoint or station
        % app.insertInstr was selected from UITable3: see UITable3CellSelection(app, event)
            [app.S,app.C] = Insert_Instrument(app.S,app.C,app.js,app.insertInstr);

            if app.DirectUpdateDateTimeCheckBox.Value
            % if direct update switch is active
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                update_tab_cruise(app);
                app.UITable2.Data = app.C{1,app.js};
                app.UITable.Data = app.S;
                [~,~,~,HH,MM,~] = datevec(app.S.Duration(app.js)./24);
                app.DurationHHMMEditField.Value= [num2str(HH) ':' num2str(MM)];                                
                % update track, stations and waypoints only
                app = update_map(app);
                                
                app.Lamp_4.Color = [0 1 0];
            else
                app.UITable2.Data = app.C{1,app.js};
                app.Lamp_4.Color = [1 0 0];
            end
        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
             app.ji = event.Indices(1);
             app.insertInstr = app.INSTR.Instrument(app.ji);
        end

        % Button pushed function: shiftcastupButton
        function shiftcastupButtonPushed(app, event)
        % Users can change the order of the Casts using button [shift cast
        % up] or [shift cast down]; see corresponding function below
            [app.C] = ShiftUpDown(1,app.C,app.js,app.jc);
            app.UITable2.Data = app.C{1,app.js};
            
            if app.DirectUpdateDateTimeCheckBox.Value
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                % update cruise tab
                update_tab_cruise(app);
                app.UITable2.Data = app.C{1,app.js};
                app.UITable.Data = app.S;
                app.Lamp_4.Color = [0 1 0];
            else
                app.UITable2.Data = app.C{1,app.js};
                app.Lamp_4.Color = [1 0 0];
            end
        end

        % Button pushed function: UpdateDateTimeButton
        function UpdateDateTimeButtonPushed(app, event)
        % Button [Update DateTime] in Tab Cast
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                update_tab_cruise(app);
                app.UITable.Data = app.S;
                app.UITable2.Data = app.C{1,app.js};
                [~,~,~,HH,MM,~] = datevec(app.S.Duration(app.js)./24);
                app.DurationHHMMEditField.Value= [num2str(HH) ':' num2str(MM)];               
                % update_map
%                 app = update_map(app);

                app.Lamp_4.Color = [0 1 0];
        end

        % Button pushed function: shiftcastdownButton
        function shiftcastdownButtonPushed(app, event)
        % Users can change the order of the Casts using button [shift cast
        % down] or [shift cast up]; see corresponding function above.

            [app.C] = ShiftUpDown(0,app.C,app.js,app.jc);
            app.UITable2.Data = app.C{1,app.js};

            if app.DirectUpdateDateTimeCheckBox.Value
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                % update cruise tab
                update_tab_cruise(app);
                app.UITable2.Data = app.C{1,app.js};
                app.UITable.Data = app.S;
                app.Lamp_4.Color = [0 1 0];
            else
                app.UITable2.Data = app.C{1,app.js};
                app.Lamp_4.Color = [1 0 0];
            end
           
        end

        % Value changed function: SetTimeHMEditField
        function SetTimeHMEditFieldValueChanged(app, event)
        % if Set Time in panel Set End of Cast tab was changed
            app.Lamp_2.Color = [1 0 0];           
        end

        % Value changed function: DDMMmmEditField
        function DDMMmmEditFieldValueChanged(app, event)
        % if input to conwert DD MM.mmm to decimal degrees was changed;
        % Station tab panel Convert DM to Degrees. If users like to change
        % Latitude or Longitude they must enter it in degrees. In this
        % panal they can make the conversion.
            value = app.DDMMmmEditField.Value;
            x = str2num(value);
            xdegree = dm2degrees(x);
            app.DDddEditField.Value = xdegree;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
        % Rest Start Ror, End Row and Insert after Row; see panel "Chnage
        % List" in Station tab
            app.StartRowEditField.Value = 0;
            app.EndRowEditField.Value = 0;
            app.InsertafterRowEditField.Value = 0;
            app.ExecuteButton.Enable = 0;
            app.ChangeButton.Enable = 0;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
        % Map tab: [Select] button pressed. To insert a row-number (station
        % table) is needed. If not already given; see deplyed Nr., here the
        % user can select a waypoint or station using the mouse cursor
        % after which to insert an new waypoint

            [latS,lonS] = inputm(1);
            
            dist = nan(1,size(app.S,1));            
            % find the position near to the selected point
            for i=1:size(app.S,1)
                dist(i)=deg2nm(distance('rh',latS,lonS,app.S.Latitude(i),app.S.Longitude(i)));
            end
            app.rowm = find(min(dist)==dist);
            % mark selected
            app.hh1=plotm(app.S.Latitude(app.rowm),app.S.Longitude(app.rowm));
            set(app.hh1,'LineStyle','none','Marker','.',...
                'MarkerSize',16,...
                'MarkerEdgeColor',[0 0 1], ...
                'MarkerFaceColor',[0 0 1]);
            
            app.S.Nr(app.rowm) = app.rowm;
            % display selected Nr. in MapTab
            app.NrinStationTabEditField.Value = app.rowm;
            % app.UITable.Data = app.S;
        end

        % Button pushed function: InsertButton_2
        function InsertButton_2Pushed(app, event)
        % Map tab: [Insert] button pressed. User can select a point inside
        % the map using the mouse curser
            
            app.data.pkt(1,1) = app.S.Latitude(app.rowm);
            app.data.pkt(1,2) = app.S.Longitude(app.rowm);
            % nur rowm + 1, wenn dieser Punkt auch aktiv ist.
            for i=app.rowm+1:size(app.S,1)
                if app.S.Used(i)
                    break;
                end
            end
            app.data.pkt(2,1) = app.S.Latitude(i);
            app.data.pkt(2,2) = app.S.Longitude(i);
            p=rblinem_insert(app.data,app.mstruct);
            [app.H,app.S,app.C,~] = Change_Stat_Cast(app.H,app.S,app.C,'insert',app.rowm,0);
            app.S.Latitude(app.rowm+1) = p(1);
            app.S.Longitude(app.rowm+1) = p(2);
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            update_tab_cruise(app);
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,1}; 
            % update track, stations and waypoints only
            app = update_map(app);
            % update cruise tab
            update_tab_cruise(app);
            % Update NrinStationTabEditField that to continue inserting a
            % new waypoint after the last inserted one.
            app.NrinStationTabEditField.Value = app.rowm + 1;
            app.rowm = app.rowm + 1;
        end

        % Value changed function: StartRowEditField
        function StartRowEditFieldValueChanged(app, event)
        % Execute in Station tab is enabled if Start Row n.e. 0
            app.ExecuteButton.Enable = 1;
            app.ChangeButton.Enable = 1;
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
        % Execute dialog-app to create a new cruise: menu [File - New]
            app.DialogApp = DialogNew_Cruise(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
        % Menu [File - Open]: change from current cruse to another cruise
        % stored in ..\Cruise; stored as *.cfg.mat. * :== cruise name
            % display all *_cfg.mat saved in subfolder Cruise
            cfg_file = fullfile(app.pn,'Cruise','*_cfg.mat');
            %... and select one
            [fnx,pnx] = uigetfile(cfg_file,'Open CFG-File');
            infi = fullfile(pnx,fnx);
            % (1) copy "cruise"_cfg.mat to Perplex7_cfg.mat in main
            % programfolder
            cfg_file = fullfile(app.pn,'Perplex7_cfg.mat');          
            copyfile(infi,cfg_file);
            startupFcn(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        % Menu [File - Save]: to save current cruise
            CR = app.CruiseEditField.Value;          
            outfile = fullfile(app.pn,'Cruise',[CR '.mat']);
            S = app.S;
            C = app.C;
            H = app.H;
            I = app.I;
            save(outfile,'S','C','H','I');
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
        % Menu [File - Save As]: to save current cruise into another file
        % name
            app.newcr = app.CruiseEditField.Value;
            app.DialogApp1 = DialogSaveAs(app,app.newcr);
        end

        % Menu selected function: ImportfromtxtfileMenu
        function ImportfromtxtfileMenuSelected(app, event)
        % Menu [In-Out - Import from txt-file]
        
            % If Start Row = 0 (see Change List in Station tab) waypoints
            % will be inserted after the departure port. Otherwise a row
            % must be selected 
            nr = app.StartRowEditField.Value;
            if nr == 0
                nr = nr + 1;
            end
            % Import waypoints from txt-file
            [app.S,app.C] = ImportTXT2Cruise_table(app.S,app.C,nr);
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            
            % update the station and cast table:
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,1};
          
            % update track, stations and waypoints only
            app = update_map(app);                        
            % update Cruise TAB
            update_tab_cruise(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
        % Make sure saving cruise  before closing the app.
            delete(app);
        end

        % Value changed function: ChangeArrivalTimeHHMMEditField
        function ChangeArrivalTimeHHMMEditFieldValueChanged(app, event)
        % Change Arrival Date Time at selected waypoint; see 
        % RemainingTimetoEditFieldValueChanged(app, event)
            dt = app.ChangeArrivalDateDatePicker.Value; % get date from DatePicker (format: datetime) 
            value = app.ChangeArrivalTimeHHMMEditField.Value; % value is "HH:MM"
            c = textscan(value,'%f%f','Delimiter',':');
            c = cell2mat(c);
            % take dt and add hours and minutes from value respectivily
            % c(1) ---> hours and c(2) ---> minutes
            % !!! got error when using dt = dt + hours(c(1) + minutes(c(2))
            % it works if dt was not entered using DatePicker!!!
            n = datevec(dt);
            n(4) = c(1); % hours
            n(5) = c(2); % minutes
            dt = datetime(n);
            % overwrite app.ArrivalDateTimeEditField
            app.ArrivalDateTimeEditField.Value = string(dt);
            app.H.ArrivalDateTimeSelectedWP = string(dt);
            % Compute remaining time to selected WP
            app.H = cruise_info_time2wp(app.H,app.S);
            app.RemainingTimeDaysHoursEditField.Value = app.H.RemainTimeToWP;
        end

        % Value changed function: RemainingTimetoEditField
        function RemainingTimetoEditFieldValueChanged(app, event)
        % In Cruise tab the user can enter a "string" which was first
        % entered in Station table in column "Comment", e.g. End-of-Work.
        % Than the Station table was searched for "string" and teh
        % corresponding Date/Time was displayed in the Arrival Date Time
        % Afterwards the user can change the Date/Time, using function: 
        % ChangeArrivalTimeHHMMEditFieldValueChanged(app, event) with
        % datepicker and adding HH:MM
            value = app.RemainingTimetoEditField.Value;
            if ~isempty(value)
                app.selwp = find(strcmp(app.S.Comment,value));
                s = app.S.DateTime(app.selwp);
                if ~isempty(s)
                    app.ArrivalDateTimeEditField.Value = string(s);
                    app.H.SelectedWP = string(value);
                    app.RemainingTimeDaysHoursEditField.Value = '';
                    app.ChangeArrivalTimeHHMMEditField.Value = '00:00';
                else
                    app.ArrivalDateTimeEditField.Value = 'Concurrence failed!';
                    app.H.SelectedWP = '';
                end
            else
                app.ArrivalDateTimeEditField.Value = 'Enter search string!';
                app.H.SelectedP = '';
            end
        end

        % Value changed function: NumberoftheFirstStationEditField
        function NumberoftheFirstStationEditFieldValueChanged(app, event)
            app.H.StartStationNumber = app.NumberoftheFirstStationEditField.Value;
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            app.UITable.Data = app.S;
        end

        % Value changed function: DepartureEditField
        function DepartureEditFieldValueChanged(app, event)
        % If necessary, the departure date/time can be modifided directly in the
        % Cruise tab - do not change the date/time format.
            value = app.DepartureEditField.Value;
            c = textscan(value,'%*s%s','delimiter',';');
            dt = datetime(char(c{1}));
            % update header:
            app.H.DepartureDateTime = char(c{1});
            % update departure datetime in table S:
            app.S.DateTime(1) = dt;
            % update_DateTime
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            app.UITable.Data = app.S;
            update_tab_cruise(app);
        end

        % Value changed function: ArrivalEditField
        function ArrivalEditFieldValueChanged(app, event)
        % If necessary, the arrival date/time can be modifided directly in the
        % Cruise tab - do not change the date/time format.
            value = app.ArrivalEditField.Value;
            c = textscan(value,'%*s%s','delimiter',';');
            dt = datetime(char(c{1}));
            % update header:
            app.H.ArrivalDateTime = char(c{1});
            update_tab_cruise(app);
            
        end

        % Menu selected function: ExporttoExcelMenu
        function ExporttoExcelMenuSelected(app, event)
        % Menu [In-Out - Export to Excel]
            % export app.S and app.C (Type=="ST" only) to Excel
            % fnS = Excel file name for Station table
            fnS = fullfile(app.pn,'Export',[app.fn '.xlsx']);
            writetable(app.S,fnS);
            % For each S.Type == "ST" write Excel
            % (a) identify row where S.Type == "ST":
            nr = app.S.Nr(~isnan(app.S.StatNr));
            % (b) write casts to Excel for each station
            for i = 1:length(nr)
                fnC = fullfile(app.pn,'Export', ...
                [app.fn '-Stat-' num2str(app.S.StatNr(nr(i))) '.xlsx']);
                writetable(app.C{1,nr(i)},fnC);
            end
            % (c) information
            fig_info = app.UIFigure;
            s = 'Find exported files in folder ..\Export';
            uialert(fig_info, s, ...
                    'Export',...
                    'Icon','success');
        end

        % Menu selected function: DailyscheduleMenu
        function DailyscheduleMenuSelected(app, event)
        % Menu [In-Out - Daily schedule]
        % select Start Row and End Row first!
            r1 = app.StartRowEditField.Value;
            r2 = app.EndRowEditField.Value;
            if r2 == 0
               row = r1;
            else
               row = r1:1:r2;
            end
            % Save schedule in ..\Export as txt-file, one txt-file for each
            % selected station:
            print_schedule(row,app.S,app.C,app.pn);
            % (c) information
            fig_info = app.UIFigure;
            s = 'Find daily schedule in folder ..\Export';
            uialert(fig_info, s, ...
                    'Export',...
                    'Icon','success');

        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
        % Clear zoom box if it was not corretly placed then redraw and get
        % LATLIM, LOMLIM from Station table
            clear app.data.LATLIM app.data.LONLIM;
            % Update Map Limits
            app.data.LATLIM = [min(app.S.Latitude) max(app.S.Latitude)];
            app.data.LONLIM = [min(app.S.Longitude) max(app.S.Longitude)];
            app.UITable5.Data.Min(1) = app.data.LATLIM(1);
            app.UITable5.Data.Max(1) = app.data.LATLIM(2);
            app.UITable5.Data.Min(2) = app.data.LONLIM(1);
            app.UITable5.Data.Max(2) = app.data.LONLIM(2);
            % if there are only port of departure and port of arrival and
            % both are the same location:
            % use worldmap
            latrange = range(app.data.LATLIM);
            lonrange = range(app.data.LONLIM);
            if latrange == 0 && lonrange == 0
                app.UITable5.Data.Min(1) = -90;
                app.UITable5.Data.Max(1) = 90;
                app.UITable5.Data.Min(2) = -180;
                app.UITable5.Data.Max(2) = 180;
                app.GS = app.G;
                app.GEBCOCheckBox.Value = 0;
                app.data.LATLIM = [-90 90];
                app.data.LONLIM = [-180 180];
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Execept the selected zoom box and draw new map
            % New subset of GRIDONE_2D for plotting contourlines in the map
            app.GS = GRIDONE_2D_subset(app.G, app.data);
            % read contour lines to be plotted
            app.depthcontour = str2num(app.DepthEditField.Value);
            % Update Plot
            cla(app.UIAxes);
            [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            r1 = app.StartRowEditField.Value;
            r2 = app.EndRowEditField.Value;
            speed = app.ShipSpeedknotsEditField.Value;
            if r1 >=1 && r2 >=1
                app.Lamp.Color = [1 0 0];
                app.S.Speed(r1:r2) = speed;
                app.UITable.Data = app.S;
                app.S = app.UITable.DisplayData;
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                update_tab_cruise(app);
                app.UITable.Data = app.S;
                app.UITable2.Data = app.C{1,1};
                % update track, stations and waypoints only
                app = update_map(app);
                % Update Cruise Details:
                app.H = cruise_info(app.H,app.S);
                % display output:
                app.AvailableTimedayshoursEditField.Value = app.H.TotalTime;
                app.CurrentTimeuseddayshoursEditField.Value = app.H.WorkAtSea;
                app.TotalRoutenauticalmilesEditField.Value = app.H.Route;
                app.AvgSpeedknotsEditField.Value = app.H.AvgShipSpeed;
                app.TimetogodayshoursEditField.Value = app.H.TimeTOgo;
                app.RemainingTimetoPortdayshoursEditField.Value = app.H.RemainTime;
                % green light indicates that everthing is up to date
                app.Lamp.Color = [0 1 0];
            else
                app.ChangeButton.Enable = 0;
            end
        end

        % Value changed function: EEZCheckBox
        function EEZCheckBoxValueChanged(app, event)
            app.plteez = app.EEZCheckBox.Value;
            if app.plteez
                app.pltgebco = 0;
                app.GEBCOCheckBox.Value = 0;
            end
            app.depthcontour = str2num(app.DepthEditField.Value);
            [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);
        end

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            % Execute when pressing [open] from tab cruise to select a HDF
            % file with sea ice concentration
            % Note: save HDF-files in folder "Dataset" of our main Perplex7 working directory 
            sci_file = fullfile(app.pn,'Dataset','*.hdf'); % see startupFcn concerning app.pn 
            % uigetfile shows all HDF files, select the needed date
            [app.scifn,app.scipn] = uigetfile(sci_file,'Open HDF-File');
            % display the filename of the selected HDF file in TAB Cruise
            app.SeaiceconcentrationHDFfileEditField.Value = app.scifn;
            app.SCIfile = fullfile(app.scipn,app.scifn);
            % disable EEZ and GEBCO
            app.EEZCheckBox.Enable = "off";
            app.GEBCOCheckBox.Enable = "off";
            app.EEZCheckBox.Value = 0;
            app.GEBCOCheckBox.Value = 0;
            
            % Update Plot
            cla(app.UIAxes);
            app.plteez = app.EEZCheckBox.Value;
            app.pltgebco = app.GEBCOCheckBox.Value;
            [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);

        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            % Execute when pressing [clear] from tab cruise to clear 
            % app.SeaiceconcentrationHDFfileEditField
            app.SeaiceconcentrationHDFfileEditField.Value = '';
            % clear app.scifn too:
            app.scifn ='';
            app.SCIfile ='';
            % disable EEZ and GEBCO
            if app.EEZnotInDataset
                app.EEZCheckBox.Enable = "off";
            else
                app.EEZCheckBox.Enable = "on";
            end
            app.GEBCOCheckBox.Enable = "on";
            app.EEZCheckBox.Value = 0;
            app.GEBCOCheckBox.Value = 0;
            
            % Update Plot
            cla(app.UIAxes);
            app.plteez = app.EEZCheckBox.Value;
            app.pltgebco = app.GEBCOCheckBox.Value;
            app.depthcontour = str2num(app.DepthEditField.Value);

            [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);

        end

        % Display data changed function: UITable5
        function UITable5DisplayDataChanged(app, event)
            app.data.LATLIM = [app.UITable5.DisplayData.Min(1) app.UITable5.DisplayData.Max(1)];
            app.data.LONLIM = [app.UITable5.DisplayData.Min(2) app.UITable5.DisplayData.Max(2)];
            % check typing error when changing LATLIM and/or LONLIM
            if app.data.LATLIM(1) > app.data.LATLIM(2)
               fig_err = app.UIFigure;
                s = sprintf('%s','Latmin > Latmax');
                uialert(fig_err,s,'Check Latmin!');
                return
            end
            if app.data.LATLIM(2) < app.data.LATLIM(1)
               fig_err = app.UIFigure;
                s = sprintf('%s','Latmax < Latmin');
                uialert(fig_err,s,'Check Latmax!');
                return
            end
            if app.data.LONLIM(1) > app.data.LONLIM(2)
               fig_err = app.UIFigure;
                s = sprintf('%s','Lonmin > Lonmax');
                uialert(fig_err,s,'Check Lonmin!');
                return
            end
            if app.data.LONLIM(2) < app.data.LONLIM(1)
               fig_err = app.UIFigure;
                s = sprintf('%s','Lonmax < Lonmin');
                uialert(fig_err,s,'Check Lonmax!');
                return
            end

        end

        % Value changed function: DepthEditField
        function DepthEditFieldValueChanged(app, event)
            app.pltgebco = app.GEBCOCheckBox.Value;
            if app.pltgebco
                app.plteez = 0;
                % Update Plot
                cla(app.UIAxes);
                app.depthcontour = str2num(app.DepthEditField.Value);
                [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);
            end
        end

        % Value changed function: GEBCOCheckBox
        function GEBCOCheckBoxValueChanged(app, event)
            app.pltgebco = app.GEBCOCheckBox.Value;
            if app.pltgebco
                app.plteez = 0;
                app.EEZCheckBox.Value = 0;
            end
            app.depthcontour = str2num(app.DepthEditField.Value);
            cla(app.UIAxes);
            [app.mstruct,app.h] = Plot_Map(app.pn,app.S,app.GS,app.data,app.pltgebco,app.plteez,app.SCIfile,app.land,app.depthcontour);
        end

        % Button pushed function: DeleteselectedCastButton
        function DeleteselectedCastButtonPushed(app, event)
        % delete the selected CastNr.

            if size(app.C{1,app.js},1) == 1
                app.C{1,app.js}.Type(1) = 'WP';
                app.C{1,app.js}.Done(1) = 0;
                app.C{1,app.js}.Used(1) = 1;
                app.C{1,app.js}.CastNr(1) = NaN;
                app.C{1,app.js}.Instrument(1) = "-";
                app.C{1,app.js}.DateTime(1) = NaT;
                app.C{1,app.js}.Duration(1) = NaN;
                app.C{1,app.js}.Delay(1) = NaN;
                app.C{1,app.js}.Depth(1) = NaN;
                app.C{1,app.js}.Comment(1) = "Waypoint";
            else
                app.C{1,app.js}(app.jc(1),:)=[];
            end


            app.UITable2.Data = app.C{1,app.js};
            
            if app.DirectUpdateDateTimeCheckBox.Value
                % update_DateTime
                [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
                % update cruise tab
                update_tab_cruise(app);
                app.UITable2.Data = app.C{1,app.js};
                app.UITable.Data = app.S;
                app.Lamp_4.Color = [0 1 0];
            else
                app.UITable2.Data = app.C{1,app.js};
                app.Lamp_4.Color = [1 0 0];
            end
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
            app.CloneNrEditField.Value = 0;
            % clone from 
            app.fromNrEditField.Value = 0;
            % to
            app.toNrEditField_2.Value = 0;

        end

        % Button pushed function: CloneButton_2
        function CloneButton_2Pushed(app, event)
            % Check Clone Settings:
            fig_err = app.UIFigure;
            if app.CloneNrEditField.Value==0
                uialert(fig_err,'Complete Clone Settings','Error');
                return;
            elseif app.fromNrEditField.Value == 0
                uialert(fig_err,'Complete Clone Settings','Error');
                return;
            end
            if app.fromNrEditField.Value ~= 0 && app.toNrEditField_2.Value == 0
                app.toNrEditField_2.Value = app.fromNrEditField.Value;
            end
    
            % Clone the Casts from Number in Station Tab
            % CloneNr = app.NrinStationTabEditField_2.Value;
            CloneNr = app.CloneNrEditField.Value;
            % clone from 
            startClone = app.fromNrEditField.Value;
            % to
            endClone = app.toNrEditField_2.Value;
    
            for k = startClone:endClone
                % is the depth in cloned cast < water depth
                % (a) wd = app.S.Depth(k)
                X = app.C{1,CloneNr}; % copy of app.C{1,CloneNr}
                wd = app.S.Depth(CloneNr);
                wdc = app.C{1,CloneNr}.Depth;
                for i = 1:length(wdc)
                    if wdc(i) < wd
                        X.Depth(i) = app.C{1,CloneNr}.Depth(i);
                    else
                        X.Depth(i) = app.S.Depth(k);
                    end
                end


                if strcmp(app.C{1,k}.Type,"WP")
                    app.C{1, k} = X;
                else
                    % app.C{1, k} = [app.C{1,k};app.C{1,CloneNr}];
                    app.C{1, k} = [app.C{1,k};X];
                end
            end
            % update
            [app.H,app.S,app.C] = update_DateTime(app.H,app.S,app.C,app.I,app.G.latgrd,app.G.longrd,app.G.Zgrd);
            update_tab_cruise(app);
            app.UITable.Data = app.S;
            app.UITable2.Data = app.C{1,startClone};
            [~,~,~,HH,MM,~] = datevec(app.S.Duration(app.js)./24);
            app.DurationHHMMEditField.Value= [num2str(HH) ':' num2str(MM)];                           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.InOutMenu = uimenu(app.UIFigure);
            app.InOutMenu.Text = 'In-Out';
            app.ImportfromtxtfileMenu = uimenu(app.InOutMenu);
            app.ImportfromtxtfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfromtxtfileMenuSelected, true);
            app.ImportfromtxtfileMenu.Text = 'Import from txt-file';
            app.ExporttoExcelMenu = uimenu(app.InOutMenu);
            app.ExporttoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoExcelMenuSelected, true);
            app.ExporttoExcelMenu.Text = 'Export to Excel';
            app.DailyscheduleMenu = uimenu(app.InOutMenu);
            app.DailyscheduleMenu.MenuSelectedFcn = createCallbackFcn(app, @DailyscheduleMenuSelected, true);
            app.DailyscheduleMenu.Text = 'Daily schedule';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CruiseTab = uitab(app.TabGroup);
            app.CruiseTab.Title = 'Cruise';
            app.CruiseEditFieldLabel = uilabel(app.CruiseTab);
            app.CruiseEditFieldLabel.Text = 'Cruise';
            app.CruiseEditField = uieditfield(app.CruiseTab, 'text');
            app.DepartureEditFieldLabel = uilabel(app.CruiseTab);
            app.DepartureEditFieldLabel.Text = 'Departure';
            app.DepartureEditField = uieditfield(app.CruiseTab, 'text');
            app.DepartureEditField.ValueChangedFcn = createCallbackFcn(app, @DepartureEditFieldValueChanged, true);
            app.ArrivalEditFieldLabel = uilabel(app.CruiseTab);
            app.ArrivalEditFieldLabel.Text = 'Arrival';
            app.ArrivalEditField = uieditfield(app.CruiseTab, 'text');
            app.ArrivalEditField.ValueChangedFcn = createCallbackFcn(app, @ArrivalEditFieldValueChanged, true);
            app.TotalRoutenauticalmilesEditFieldLabel = uilabel(app.CruiseTab);
            app.TotalRoutenauticalmilesEditFieldLabel.Text = 'Total  Route  (nautical miles)';
            app.TotalRoutenauticalmilesEditField = uieditfield(app.CruiseTab, 'numeric');
            app.AvgSpeedknotsEditFieldLabel = uilabel(app.CruiseTab);
            app.AvgSpeedknotsEditFieldLabel.Text = 'Avg. Speed (knots)';
            app.AvgSpeedknotsEditField = uieditfield(app.CruiseTab, 'numeric');
            app.AvailableTimedayshoursEditFieldLabel = uilabel(app.CruiseTab);
            app.AvailableTimedayshoursEditFieldLabel.Text = ' Available Time (days hours)';
            app.AvailableTimedayshoursEditField = uieditfield(app.CruiseTab, 'text');
            app.CurrentTimeuseddayshoursEditFieldLabel = uilabel(app.CruiseTab);
            app.CurrentTimeuseddayshoursEditFieldLabel.Text = 'Current Time used (days hours)';
            app.CurrentTimeuseddayshoursEditField = uieditfield(app.CruiseTab, 'text');
            app.RemainingTimetoPortdayshoursEditFieldLabel = uilabel(app.CruiseTab);
            app.RemainingTimetoPortdayshoursEditFieldLabel.Text = 'Remaining Time to Port (days hours)';
            app.RemainingTimetoPortdayshoursEditField = uieditfield(app.CruiseTab, 'text');
            app.TimetogodayshoursEditFieldLabel = uilabel(app.CruiseTab);
            app.TimetogodayshoursEditFieldLabel.Text = 'Time to go (days hours)';
            app.TimetogodayshoursEditField = uieditfield(app.CruiseTab, 'text');
            app.RemainingTimetoEditFieldLabel = uilabel(app.CruiseTab);
            app.RemainingTimetoEditFieldLabel.Text = 'Remaining Time to ....';
            app.RemainingTimetoEditField = uieditfield(app.CruiseTab, 'text');
            app.RemainingTimetoEditField.ValueChangedFcn = createCallbackFcn(app, @RemainingTimetoEditFieldValueChanged, true);
            app.ArrivalDateTimeEditFieldLabel = uilabel(app.CruiseTab);
            app.ArrivalDateTimeEditFieldLabel.Text = 'Arrival DateTime';
            app.ArrivalDateTimeEditField = uieditfield(app.CruiseTab, 'text');
            app.ChangeArrivalDateDatePickerLabel = uilabel(app.CruiseTab);
            app.ChangeArrivalDateDatePickerLabel.Text = 'Change Arrival Date';
            app.ChangeArrivalDateDatePicker = uidatepicker(app.CruiseTab);
            app.EntertheCommenttosearchintheStationTableLabel = uilabel(app.CruiseTab);
            app.EntertheCommenttosearchintheStationTableLabel.Text = '(Enter the Comment to search in the Station Table)';
            app.RemainingTimeDaysHoursEditFieldLabel = uilabel(app.CruiseTab);
            app.RemainingTimeDaysHoursEditFieldLabel.Text = 'Remaining Time (Days Hours)';
            app.RemainingTimeDaysHoursEditField = uieditfield(app.CruiseTab, 'text');
            app.HoursMinutesLabel = uilabel(app.CruiseTab);
            app.HoursMinutesLabel.Text = 'Change Arrival Time (HH:MM)';
            app.ChangeArrivalTimeHHMMEditField = uieditfield(app.CruiseTab, 'text');
            app.ChangeArrivalTimeHHMMEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeArrivalTimeHHMMEditFieldValueChanged, true);
            app.ChangeArrivalTimeHHMMEditField.Value = '00:00';
            app.NumberoftheFirstStationEditFieldLabel = uilabel(app.CruiseTab);
            app.NumberoftheFirstStationEditFieldLabel.Text = 'Number of the  First Station';
            app.NumberoftheFirstStationEditField = uieditfield(app.CruiseTab, 'numeric');
            app.NumberoftheFirstStationEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoftheFirstStationEditFieldValueChanged, true);
            app.NumberoftheFirstStationEditField.Value = 1;
            app.Image = uiimage(app.CruiseTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AWI_WortBildmarke_Farbe_CMYK.jpg');
            app.Perplex7Label = uilabel(app.CruiseTab);
            app.Perplex7Label.Text = 'Perplex7';
            app.ProgramforExpeditionRoutePLanningandEXecutionLabel = uilabel(app.CruiseTab);
            app.ProgramforExpeditionRoutePLanningandEXecutionLabel.Text = 'Program for Expedition Route PLanning and EXecution';
            app.Release1500fromAugust2024Label = uilabel(app.CruiseTab);
            app.Release1500fromAugust2024Label.Text = 'Release: 1.5.0.0 from August 2024';
            app.AuthorGerdRohardtLabel = uilabel(app.CruiseTab);
            app.AuthorGerdRohardtLabel.Text = 'Author: Gerd Rohardt';
            app.ContactGerdRohardtewetelnetLabel = uilabel(app.CruiseTab);
            app.ContactGerdRohardtewetelnetLabel.Text = 'Contact: Gerd.Rohardt@ewetel.net';
            app.Image2 = uiimage(app.CruiseTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Pinguin.jpg');
            app.SeaiceconcentrationHDFfileEditFieldLabel = uilabel(app.CruiseTab);
            app.SeaiceconcentrationHDFfileEditFieldLabel.Text = 'Sea ice concentration HDF -file';
            app.SeaiceconcentrationHDFfileEditField = uieditfield(app.CruiseTab, 'text');
            app.openButton = uibutton(app.CruiseTab, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.clearButton = uibutton(app.CruiseTab, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.StationsTab = uitab(app.TabGroup);
            app.StationsTab.Title = 'Stations';
            app.ChangeListButtonGroup = uibuttongroup(app.StationsTab);
            app.ChangeListButtonGroup.Title = 'Change List';
            app.deleteButton = uiradiobutton(app.ChangeListButtonGroup);
            app.deleteButton.Text = 'delete';
            app.deleteButton.Value = true;
            app.insertButton = uiradiobutton(app.ChangeListButtonGroup);
            app.insertButton.Text = 'insert';
            app.CpasteButton = uiradiobutton(app.ChangeListButtonGroup);
            app.CpasteButton.Text = 'C-paste';
            app.XpasteButton = uiradiobutton(app.ChangeListButtonGroup);
            app.XpasteButton.Text = 'X-paste';
            app.Lamp_5 = uilamp(app.ChangeListButtonGroup);
            app.ExecuteButton = uibutton(app.ChangeListButtonGroup, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.StartRowEditFieldLabel = uilabel(app.ChangeListButtonGroup);
            app.StartRowEditFieldLabel.Text = 'Start Row';
            app.StartRowEditField = uieditfield(app.ChangeListButtonGroup, 'numeric');
            app.StartRowEditField.ValueChangedFcn = createCallbackFcn(app, @StartRowEditFieldValueChanged, true);
            app.InsertafterRowEditFieldLabel = uilabel(app.ChangeListButtonGroup);
            app.InsertafterRowEditFieldLabel.Text = 'Insert after Row';
            app.InsertafterRowEditField = uieditfield(app.ChangeListButtonGroup, 'numeric');
            app.EndRowEditFieldLabel = uilabel(app.ChangeListButtonGroup);
            app.EndRowEditFieldLabel.Text = 'End Row';
            app.EndRowEditField = uieditfield(app.ChangeListButtonGroup, 'numeric');
            app.ClearButton = uibutton(app.ChangeListButtonGroup, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.orderButton = uiradiobutton(app.ChangeListButtonGroup);
            app.orderButton.Text = 'order';
            app.UpdateButton = uibutton(app.StationsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.Lamp = uilamp(app.StationsTab);
            app.UITable = uitable(app.StationsTab);
            app.UITable.ColumnName = {'Nr'; 'Type'; 'Proc'; 'Active'; 'StatNr'; 'DateTime'; 'Latitude'; 'Longitude'; 'Depth'; 'Speed'; 'Way to go'; 'Delay'; 'No. Casts'; 'Duration'; 'Comment'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true true false true true true true true false true false false true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.Multiselect = 'off';
            app.ConvertDMtoDegreesPanel = uipanel(app.StationsTab);
            app.ConvertDMtoDegreesPanel.Title = 'Convert DM to Degrees';
            app.DDddEditFieldLabel = uilabel(app.ConvertDMtoDegreesPanel);
            app.DDddEditFieldLabel.Text = 'DD.dd';
            app.DDddEditField = uieditfield(app.ConvertDMtoDegreesPanel, 'numeric');
            app.DDddEditField.ValueDisplayFormat = '%5.4f';
            app.DDMMmmLabel = uilabel(app.ConvertDMtoDegreesPanel);
            app.DDMMmmLabel.Text = '+/-DD MM.mm';
            app.DDMMmmEditField = uieditfield(app.ConvertDMtoDegreesPanel, 'text');
            app.DDMMmmEditField.ValueChangedFcn = createCallbackFcn(app, @DDMMmmEditFieldValueChanged, true);
            app.DDMMmmEditField.Value = '-00 00.00';
            app.ChangeSpeedPanel = uipanel(app.StationsTab);
            app.ChangeSpeedPanel.Title = 'Change Speed';
            app.ShipSpeedknotsEditFieldLabel = uilabel(app.ChangeSpeedPanel);
            app.ShipSpeedknotsEditFieldLabel.Text = 'Ship  Speed [knots]';
            app.ShipSpeedknotsEditField = uieditfield(app.ChangeSpeedPanel, 'numeric');
            app.ShipSpeedknotsEditField.Value = 10;
            app.ChangeButton = uibutton(app.ChangeSpeedPanel, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Enable = 'off';
            app.ChangeButton.Text = 'Change';
            app.TextArea_2 = uitextarea(app.ChangeSpeedPanel);
            app.TextArea_2.Value = {'Use Start Row and End Row (see left panel) to change Speed in Station Table.'};
            app.CloneCastSetupfromPanel_2 = uipanel(app.StationsTab);
            app.CloneCastSetupfromPanel_2.Title = 'Clone Cast Setup from ...';
            app.CloneNrEditFieldLabel = uilabel(app.CloneCastSetupfromPanel_2);
            app.CloneNrEditFieldLabel.Text = 'Clone Nr';
            app.CloneNrEditField = uieditfield(app.CloneCastSetupfromPanel_2, 'numeric');
            app.fromStationTabLabel_2 = uilabel(app.CloneCastSetupfromPanel_2);
            app.fromStationTabLabel_2.Text = 'from Station Tab';
            app.fromNrEditFieldLabel = uilabel(app.CloneCastSetupfromPanel_2);
            app.fromNrEditFieldLabel.Text = 'from Nr';
            app.fromNrEditField = uieditfield(app.CloneCastSetupfromPanel_2, 'numeric');
            app.toNrEditField_2Label = uilabel(app.CloneCastSetupfromPanel_2);
            app.toNrEditField_2Label.Text = 'to Nr';
            app.toNrEditField_2 = uieditfield(app.CloneCastSetupfromPanel_2, 'numeric');
            app.CloneButton_2 = uibutton(app.CloneCastSetupfromPanel_2, 'push');
            app.CloneButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloneButton_2Pushed, true);
            app.CloneButton_2.Text = 'Clone';
            app.ClearButton_3 = uibutton(app.CloneCastSetupfromPanel_2, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Text = 'Clear';
            app.CastsTab = uitab(app.TabGroup);
            app.CastsTab.Title = 'Casts';
            app.SetEndofCastPanel = uipanel(app.CastsTab);
            app.SetEndofCastPanel.Title = 'Set End of Cast';
            app.SetDateDatePickerLabel = uilabel(app.SetEndofCastPanel);
            app.SetDateDatePickerLabel.Text = 'Set Date';
            app.SetDateDatePicker = uidatepicker(app.SetEndofCastPanel);
            app.SetDateDatePicker.Limits = [datetime([2015 1 1]) datetime([2050 12 31])];
            app.SetTimeHMEditFieldLabel = uilabel(app.SetEndofCastPanel);
            app.SetTimeHMEditFieldLabel.Text = ' Set Time (H M)';
            app.SetTimeHMEditField = uieditfield(app.SetEndofCastPanel, 'text');
            app.SetTimeHMEditField.ValueChangedFcn = createCallbackFcn(app, @SetTimeHMEditFieldValueChanged, true);
            app.SetTimeHMEditField.Value = '0 0';
            app.UpdateEndofCastButton = uibutton(app.SetEndofCastPanel, 'push');
            app.UpdateEndofCastButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEndofCastButtonPushed, true);
            app.UpdateEndofCastButton.Text = ' Update End of Cast';
            app.Lamp_2 = uilamp(app.SetEndofCastPanel);
            app.UITable2 = uitable(app.CastsTab);
            app.UITable2.ColumnName = {'Type'; 'Proc'; 'Active'; 'CastNr'; 'Instrument'; 'DateTime'; 'Duration'; 'Delay'; 'Depth'; 'Comment'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false true true false false true false true true true];
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @UITable2DisplayDataChanged, true);
            app.ChangeCastsPanel = uipanel(app.CastsTab);
            app.ChangeCastsPanel.Title = 'Change Casts';
            app.UITable3 = uitable(app.ChangeCastsPanel);
            app.UITable3.ColumnName = {'Instrument'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = true;
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.InsertButton = uibutton(app.ChangeCastsPanel, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert ';
            app.DirectUpdateDateTimeCheckBox = uicheckbox(app.ChangeCastsPanel);
            app.DirectUpdateDateTimeCheckBox.Text = 'Direct Update DateTime';
            app.shiftcastupButton = uibutton(app.ChangeCastsPanel, 'push');
            app.shiftcastupButton.ButtonPushedFcn = createCallbackFcn(app, @shiftcastupButtonPushed, true);
            app.shiftcastupButton.Text = 'shift cast up';
            app.shiftcastdownButton = uibutton(app.ChangeCastsPanel, 'push');
            app.shiftcastdownButton.ButtonPushedFcn = createCallbackFcn(app, @shiftcastdownButtonPushed, true);
            app.shiftcastdownButton.Text = 'shift cast down';
            app.UpdateDateTimeButton = uibutton(app.ChangeCastsPanel, 'push');
            app.UpdateDateTimeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDateTimeButtonPushed, true);
            app.UpdateDateTimeButton.Text = 'Update DateTime';
            app.Lamp_4 = uilamp(app.ChangeCastsPanel);
            app.DeleteselectedCastButton = uibutton(app.ChangeCastsPanel, 'push');
            app.DeleteselectedCastButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedCastButtonPushed, true);
            app.DeleteselectedCastButton.Text = 'Delete selected Cast';
            app.PositionEditFieldLabel = uilabel(app.CastsTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.CastsTab, 'text');
            app.StationNumberEditFieldLabel = uilabel(app.CastsTab);
            app.StationNumberEditFieldLabel.Text = 'Station Number';
            app.StationNumberEditField = uieditfield(app.CastsTab, 'numeric');
            app.WaterDepthmEditFieldLabel = uilabel(app.CastsTab);
            app.WaterDepthmEditFieldLabel.Text = 'Water Depth(m)';
            app.WaterDepthmEditField = uieditfield(app.CastsTab, 'numeric');
            app.WaytogonmEditFieldLabel = uilabel(app.CastsTab);
            app.WaytogonmEditFieldLabel.Text = 'Way to go (nm)';
            app.WaytogonmEditField = uieditfield(app.CastsTab, 'numeric');
            app.DurationHHMMEditFieldLabel = uilabel(app.CastsTab);
            app.DurationHHMMEditFieldLabel.Text = 'Duration (HH:MM)';
            app.DurationHHMMEditField = uieditfield(app.CastsTab, 'text');
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.UIAxes = uiaxes(app.MapTab);
            app.SelectButton = uibutton(app.MapTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.InsertButton_2 = uibutton(app.MapTab, 'push');
            app.InsertButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertButton_2Pushed, true);
            app.InsertButton_2.Text = 'Insert';
            app.ApplyButton = uibutton(app.MapTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ClearButton_2 = uibutton(app.MapTab, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.NrinStationTabEditFieldLabel = uilabel(app.MapTab);
            app.NrinStationTabEditFieldLabel.Text = 'Nr. in Station Tab';
            app.NrinStationTabEditField = uieditfield(app.MapTab, 'numeric');
            app.TextArea = uitextarea(app.MapTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'[Select] point and click on a waypoint or station, then press [Insert].'; ''; '[Insert] In the Station Tab, a Nr. must be marked after which a new waypoint is to be inserted. Alternatively, press [Select] first.'; ''; '[Map Limits] '; '1. Edit  Min and / or Max '; '    from Latitude and / or Longitude'; '2. press [Apply].'; ''; '[Clear] + [Apply]'; 'Go back to the original map'; ''; 'Check Box EEZ to switch on/off plotting EEZ. Plotting may last a few seconds.'; ''; 'Check Box GEBCO to switch on/off plotting contour lines entered in edit field "Depth".'; 'Plotting may last a few seconds.'; ''; ''};
            app.EEZCheckBox = uicheckbox(app.MapTab);
            app.EEZCheckBox.ValueChangedFcn = createCallbackFcn(app, @EEZCheckBoxValueChanged, true);
            app.EEZCheckBox.Enable = 'off';
            app.EEZCheckBox.Text = 'EEZ';
            app.UITable5 = uitable(app.MapTab);
            app.UITable5.ColumnName = {'Name'; 'Min'; 'Max'};
            app.UITable5.RowName = {};
            app.UITable5.ColumnEditable = [false true true];
            app.UITable5.DisplayDataChangedFcn = createCallbackFcn(app, @UITable5DisplayDataChanged, true);
            app.DepthEditFieldLabel = uilabel(app.MapTab);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.MapTab, 'text');
            app.DepthEditField.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.DepthEditField.Value = '500 1000 1500';
            app.GEBCOCheckBox = uicheckbox(app.MapTab);
            app.GEBCOCheckBox.ValueChangedFcn = createCallbackFcn(app, @GEBCOCheckBoxValueChanged, true);
            app.GEBCOCheckBox.Text = 'GEBCO';
            app.InstrumentTab = uitab(app.TabGroup);
            app.InstrumentTab.Title = 'Instrument';
            app.UpdateButton_3 = uibutton(app.InstrumentTab, 'push');
            app.UpdateButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_3Pushed, true);
            app.UpdateButton_3.Text = 'Update';
            app.Lamp_3 = uilamp(app.InstrumentTab);
            app.UITable4 = uitable(app.InstrumentTab);
            app.UITable4.ColumnName = {'Active'; 'Instrument'; 'Description'; 'User'; 'Fixed'; 'Handling'; 'Down'; 'Trawling'; 'Up'};
            app.UITable4.RowName = {};
            app.UITable4.ColumnSortable = [true true false false false false false false false];
            app.UITable4.ColumnEditable = true;
            app.UITable4.DisplayDataChangedFcn = createCallbackFcn(app, @UITable4DisplayDataChanged, true);
            app.SortButton = uibutton(app.InstrumentTab, 'push');
            app.SortButton.ButtonPushedFcn = createCallbackFcn(app, @SortButtonPushed, true);
            app.SortButton.Text = 'Sort';
            app.UnitsTextAreaLabel = uilabel(app.InstrumentTab);
            app.UnitsTextAreaLabel.Text = 'Units';
            app.UnitsTextArea = uitextarea(app.InstrumentTab);
            app.UnitsTextArea.Value = {'Fixed     : hours'; 'Handling: minutes'; 'Down     : m/s'; 'Trawling : minutes'; 'Up          : m/s'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.XpasteButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Perplex7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end